classdef AEV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AEVToolUIFigure                 matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportRoutesMenu                matlab.ui.container.Menu
        ImportDistanceTimeMatrixMenu    matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        RoutesListBoxLabel              matlab.ui.control.Label
        RoutesListBox                   matlab.ui.control.ListBox
        ClearButton                     matlab.ui.control.Button
        PlotRoutesCheckBox              matlab.ui.control.CheckBox
        DepotCoordinatesLabel           matlab.ui.control.Label
        LongitudeLabel                  matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LattitudeEditFieldLabel         matlab.ui.control.Label
        LattitudeEditField              matlab.ui.control.NumericEditField
        TabGroup5                       matlab.ui.container.TabGroup
        ChargingStrategyTab             matlab.ui.container.Tab
        OvernightChargingSwitchLabel    matlab.ui.control.Label
        OvernightChargingSwitch         matlab.ui.control.Switch
        TerminalChargingSwitchLabel     matlab.ui.control.Label
        TerminalChargingSwitch          matlab.ui.control.Switch
        ChargerPowerkWEditFieldLabel    matlab.ui.control.Label
        ChargerPowerkWEditField         matlab.ui.control.NumericEditField
        SchedulingStrategyTab           matlab.ui.container.Tab
        TimetableStrategyButtonGroup    matlab.ui.container.ButtonGroup
        EvenHeadwayButton               matlab.ui.control.ToggleButton
        EvenLoadButton                  matlab.ui.control.ToggleButton
        ClocktimetableButton            matlab.ui.control.ToggleButton
        LoadPredeterminedTimetableButton  matlab.ui.control.Button
        ElectricityEnergyMixTab         matlab.ui.container.Tab
        CostConfigurationTab            matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        PropertyTargetsTab              matlab.ui.container.Tab
        PropertySlider                  matlab.ui.control.Slider
        PropertyDropDown_2Label         matlab.ui.control.Label
        SelectedProperty                matlab.ui.control.DropDown
        AttributeDropDown_2Label        matlab.ui.control.Label
        SelectedAttribute               matlab.ui.control.DropDown
        AttributeSlider                 matlab.ui.control.Slider
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SlopeSliderLabel                matlab.ui.control.Label
        SlopeSlider                     matlab.ui.control.Slider
        InflexionPointSliderLabel       matlab.ui.control.Label
        InflexionPointSlider            matlab.ui.control.Slider
        XaxisminEditFieldLabel          matlab.ui.control.Label
        XaxisminEditField               matlab.ui.control.NumericEditField
        XaxismaxEditFieldLabel          matlab.ui.control.Label
        XaxismaxEditField               matlab.ui.control.NumericEditField
        PropertyAxes                    matlab.ui.control.UIAxes
        AttributeAxes                   matlab.ui.control.UIAxes
        ValueFunctionAxes               matlab.ui.control.UIAxes
        DesignTab                       matlab.ui.container.Tab
        ExplorationFeatureTab           matlab.ui.container.TabGroup
        GeneralTab_2                    matlab.ui.container.Tab
        EvaluatePropertiesButton        matlab.ui.control.Button
        VehicleConceptsListBoxLabel     matlab.ui.control.Label
        VehicleConceptsListBox          matlab.ui.control.ListBox
        AddtoComparisonButton           matlab.ui.control.Button
        LoadVehicleButton               matlab.ui.control.Button
        OptimizeButton                  matlab.ui.control.Button
        PopulationSizeEditFieldLabel    matlab.ui.control.Label
        PopulationSizeEditField         matlab.ui.control.NumericEditField
        GenerationsEditFieldLabel       matlab.ui.control.Label
        GenerationsEditField            matlab.ui.control.NumericEditField
        ParallelPoolCheckBox            matlab.ui.control.CheckBox
        PoolSizeSpinnerLabel            matlab.ui.control.Label
        PoolSizeSpinner                 matlab.ui.control.Spinner
        GeneratePackageButton           matlab.ui.control.Button
        DefineNewVehicleTab             matlab.ui.container.Tab
        ExteriorParametersPanel         matlab.ui.container.Panel
        SingleDeckerCheckBox            matlab.ui.control.CheckBox
        DoubleDeckerCheckBox            matlab.ui.control.CheckBox
        VehicleLengthmmEditFieldLabel   matlab.ui.control.Label
        VehicleLengthmmEditField        matlab.ui.control.NumericEditField
        VehicleWidthmmEditFieldLabel    matlab.ui.control.Label
        VehicleWidthmmEditField         matlab.ui.control.NumericEditField
        VehicleHeightmmEditFieldLabel   matlab.ui.control.Label
        VehicleHeightmmEditField        matlab.ui.control.NumericEditField
        WheelbasemmEditFieldLabel       matlab.ui.control.Label
        WheelbasemmEditField            matlab.ui.control.NumericEditField
        InteriorParametersPanel         matlab.ui.container.Panel
        SeatWidthmmEditFieldLabel       matlab.ui.control.Label
        SeatWidthmmEditField            matlab.ui.control.NumericEditField
        LegroommmEditFieldLabel         matlab.ui.control.Label
        LegroommmEditField              matlab.ui.control.NumericEditField
        StandingSpacempassEditFieldLabel  matlab.ui.control.Label
        StandingSpaceEditField          matlab.ui.control.NumericEditField
        WheelchairZonesDropDownLabel    matlab.ui.control.Label
        WheelchairZonesDropDown         matlab.ui.control.DropDown
        NumberofDoorsDropDownLabel      matlab.ui.control.Label
        NumberofDoorsDropDown           matlab.ui.control.DropDown
        InteriorLayoutDropDownLabel     matlab.ui.control.Label
        InteriorLayoutDropDown          matlab.ui.control.DropDown
        AirConditioningCheckBox         matlab.ui.control.CheckBox
        AisleWidthmmEditFieldLabel      matlab.ui.control.Label
        AisleWidthmmEditField           matlab.ui.control.NumericEditField
        PowertrainParametersPanel       matlab.ui.container.Panel
        PowertrainTopologyDropDownLabel  matlab.ui.control.Label
        PowertrainTopologyDropDown      matlab.ui.control.DropDown
        TotalPowerkWEditFieldLabel      matlab.ui.control.Label
        TotalPowerkWEditField           matlab.ui.control.NumericEditField
        PowerSplitRatioEditFieldLabel   matlab.ui.control.Label
        PowerSplitRatioEditField        matlab.ui.control.NumericEditField
        NumberofGearsDropDownLabel      matlab.ui.control.Label
        NumberofGearsDropDown           matlab.ui.control.DropDown
        BatteryCapacitykWhEditFieldLabel  matlab.ui.control.Label
        BatteryCapacitykWhEditField     matlab.ui.control.NumericEditField
        SaveConceptButton               matlab.ui.control.Button
        GearRatioEditFieldLabel         matlab.ui.control.Label
        GearRatioEditField              matlab.ui.control.NumericEditField
        OptInputTable                   matlab.ui.control.Table
        ResultsTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ParetoFrontTab                  matlab.ui.container.Tab
        GenerationListBoxLabel          matlab.ui.control.Label
        GenerationListBox               matlab.ui.control.ListBox
        OptimisationStateTable          matlab.ui.control.Table
        ParetoDesigns                   matlab.ui.control.Table
        StopOptimisationButton          matlab.ui.control.Button
        Opt                             matlab.ui.control.UIAxes
        PropertyComparisonTab           matlab.ui.container.Tab
        AttributeDropDown_2Label_2      matlab.ui.control.Label
        SelectedAttribute_3             matlab.ui.control.DropDown
        PropertyDropDown_2Label_2       matlab.ui.control.Label
        SelectedProperty_2              matlab.ui.control.DropDown
        PropertyAxes_2                  matlab.ui.control.UIAxes
        AttributeAxes_2                 matlab.ui.control.UIAxes
        ValueFunctionAxes_2             matlab.ui.control.UIAxes
        VehicleSpecificationTab         matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        SpecificationsTab               matlab.ui.container.Tab
        LengthmmEditFieldLabel          matlab.ui.control.Label
        LengthmmEditField               matlab.ui.control.NumericEditField
        WidthmmEditFieldLabel           matlab.ui.control.Label
        WidthmmEditField                matlab.ui.control.NumericEditField
        HeightmmEditFieldLabel          matlab.ui.control.Label
        HeightmmEditField               matlab.ui.control.NumericEditField
        CurbMasskgEditFieldLabel        matlab.ui.control.Label
        CurbMasskgEditField             matlab.ui.control.NumericEditField
        GrossMasskgEditFieldLabel       matlab.ui.control.Label
        GrossMasskgEditField            matlab.ui.control.NumericEditField
        PassengerCapacityEditFieldLabel  matlab.ui.control.Label
        PassengerCapacityEditField      matlab.ui.control.NumericEditField
        SeatingCapacityEditFieldLabel   matlab.ui.control.Label
        SeatingCapacityEditField        matlab.ui.control.NumericEditField
        StandingCapacityEditFieldLabel  matlab.ui.control.Label
        StandingCapacityEditField       matlab.ui.control.NumericEditField
        WheelchairZonesEditFieldLabel   matlab.ui.control.Label
        WheelchairZonesEditField        matlab.ui.control.NumericEditField
        TopSpeedkmphEditFieldLabel      matlab.ui.control.Label
        TopSpeedkmphEditField           matlab.ui.control.NumericEditField
        GradeabilityLabel_2             matlab.ui.control.Label
        GradeabilityEditField           matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        TurningRadiusmEditFieldLabel    matlab.ui.control.Label
        TurningRadiusmEditField         matlab.ui.control.NumericEditField
        BatteryCapacitykWhEditField_2Label  matlab.ui.control.Label
        BatteryCapacitykWhEditField_2   matlab.ui.control.NumericEditField
        MotorPowerkWEditFieldLabel      matlab.ui.control.Label
        MotorPowerkWEditField           matlab.ui.control.NumericEditField
        GenerateVehiclePackageButton    matlab.ui.control.Button
        WheelbasemmEditField_2Label     matlab.ui.control.Label
        WheelbasemmEditField_2          matlab.ui.control.NumericEditField
        LengthmmEditField_2Label        matlab.ui.control.Label
        LengthmmEditField_2             matlab.ui.control.NumericEditField
        PackageTab                      matlab.ui.container.Tab
        PackageAxes                     matlab.ui.control.UIAxes
        TotalCostofOwnershipTab         matlab.ui.container.Tab
        CostBreakdownTab                matlab.ui.container.TabGroup
        OverallCostsTab                 matlab.ui.container.Tab
        OverallFleetCostinSGDEditFieldLabel  matlab.ui.control.Label
        OverallFleetCostinSGDEditField  matlab.ui.control.NumericEditField
        TotalCostofOwnershipinSGDEditFieldLabel  matlab.ui.control.Label
        TotalCostofOwnershipinSGDEditField  matlab.ui.control.NumericEditField
        TotalCostperkmEditFieldLabel    matlab.ui.control.Label
        TotalCostperkmEditField         matlab.ui.control.NumericEditField
        TotalCostperpassengerkmLabel    matlab.ui.control.Label
        TotalCostperpassengerkmEditField  matlab.ui.control.NumericEditField
        AcquisitionCostinSGDEditFieldLabel  matlab.ui.control.Label
        AcquisitionCostinSGDEditField   matlab.ui.control.NumericEditField
        TCOAxes                         matlab.ui.control.UIAxes
        TCOPassAxes                     matlab.ui.control.UIAxes
        AcquisitionCostsAxes            matlab.ui.control.UIAxes
        ComponentCostsTab               matlab.ui.container.Tab
        LifeCycleAssessmentTab          matlab.ui.container.Tab
        TotalFleetGHGEmissionsEditFieldLabel  matlab.ui.control.Label
        TotalFleetGHGEmissionsEditField  matlab.ui.control.NumericEditField
        GHGEmissionsperkmEditFieldLabel  matlab.ui.control.Label
        GHGEmissionsperkmEditField      matlab.ui.control.NumericEditField
        GHGEmissionsperpassengerkmEditFieldLabel  matlab.ui.control.Label
        GHGEmissionsperpassengerkmEditField  matlab.ui.control.NumericEditField
        MillionkgCO2eqLabel             matlab.ui.control.Label
        gCO2eqkmLabel                   matlab.ui.control.Label
        gCO2eqpassengerkmLabel          matlab.ui.control.Label
        GHGEmissionsAxes                matlab.ui.control.UIAxes
        VehicleAssignmentTab            matlab.ui.container.Tab
        VehicleNumberDropDownLabel      matlab.ui.control.Label
        VehicleNumberDropDown           matlab.ui.control.DropDown
        TotalDistanceDrivenkmdayEditFieldLabel  matlab.ui.control.Label
        TotalDistanceDrivenkmdayEditField  matlab.ui.control.NumericEditField
        NumberofServedTripsEditFieldLabel  matlab.ui.control.Label
        NumberofServedTripsEditField    matlab.ui.control.NumericEditField
        AssignmentPlotTab               matlab.ui.container.TabGroup
        VehicleBlockTab                 matlab.ui.container.Tab
        VehicleBlocksAxes               matlab.ui.control.UIAxes
        TimeSpaceRepresentationTab      matlab.ui.container.Tab
        TimeSpacePlotAxes               matlab.ui.control.UIAxes
        EnergyConsumedkWhLabel          matlab.ui.control.Label
        EnergyConsumedkWhEditField      matlab.ui.control.NumericEditField
        FleetSizeEditFieldLabel         matlab.ui.control.Label
        FleetSizeEditField              matlab.ui.control.NumericEditField
        TripNumberDropDownLabel         matlab.ui.control.Label
        TripNumberDropDown              matlab.ui.control.DropDown
        MeanPassengerOccupancyEditFieldLabel  matlab.ui.control.Label
        MeanPassengerOccupancyEditField  matlab.ui.control.NumericEditField
        VehicleOccupancyAxes            matlab.ui.control.UIAxes
        TimetableTab                    matlab.ui.container.Tab
        TimetableTable                  matlab.ui.control.Table
        RoutesDropDownLabel             matlab.ui.control.Label
        RoutesDropDown                  matlab.ui.control.DropDown
        DirectionDropDownLabel          matlab.ui.control.Label
        DirectionDropDown               matlab.ui.control.DropDown
        TotalTripsEditFieldLabel        matlab.ui.control.Label
        TotalTripsEditField             matlab.ui.control.NumericEditField
        TripsFrequencyAxes              matlab.ui.control.UIAxes
        PassengersTab                   matlab.ui.container.Tab
        PassengerTable                  matlab.ui.control.Table
        MeanWaitingTimeEditFieldLabel   matlab.ui.control.Label
        MeanWaitingTimeEditField        matlab.ui.control.NumericEditField
        MeanTravelTimeEditFieldLabel    matlab.ui.control.Label
        MeanTravelTimeEditField         matlab.ui.control.NumericEditField
        WaitingTimeAxes                 matlab.ui.control.UIAxes
        VisualiseTab                    matlab.ui.container.Tab
        TimeFrameSliderLabel            matlab.ui.control.Label
        TimeFrameSlider                 matlab.ui.control.Slider
        SelectVehicletoDisplayLabel     matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        PlayButton_2                    matlab.ui.control.Button
        VisAxes                         matlab.ui.control.UIAxes
        ChargingTab                     matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Services % Set of all routes defined by user
        PropertyFields={'Service Performance','Accessibility','Comfort','Functionality',...%'Luxury',...
            'Safety','Longitudinal Dynamics','Costs','Environment'};
        Targets=[10 10 10 10 10 10 10 ... %10
             10]; % Default Targets
        ServicePerformanceFields={'Waiting Time',['Dwelling' newline 'Time'],'Seating-Standing Ratio','Seat Availability','Crowdedness','Missed Boardings'};
        ServicePerformanceTargets=[10 7 8 5 7 10];
        AccessibilityFields={'Wheelchair Zones','Entry Height','Number of Doors','Door Width', 'Low Floor','Aisle Width'};
        AccessibilityTargets=[8 8 6 6 8 6];
        ComfortFields={'Seat Comfort','Legroom','Standing Comfort','Climate Comfort','Ride Comfort','Visibility','Headroom','NVH'};
        ComfortTargets=[5 8 10 6 9 6 7 7];
        FunctionalityFields={'Information','Payment','Storage Space','Power Outlets'};
        FunctionalityTargets=[6 8 7 5];
     %   LuxuryFields={'Luxury Seats','Privacy Seating','Private Standing Space'};
      %  LuxuryTargets=[5 8 10];
        SafetyFields={'Roll Stability','Security Cameras','Seatbelts','Support Handles'};
        SafetyTargets=[5 8 10 6];
        LongitudinalDynamicsFields={'Speed','Acceleration','Gradability'};
        LongitudinalDynamicsTargets=[5 8 10];
        CostsFields={'TCO','Capex','Opex'};
        CostsTargets=[5 8 10];
        EnvironmentFields={'GHG Emissions','Turning Radius','Use Phase Emissions'};
        EnvironmentTargets=[9 7 10 ];
        AttributeTargets;
        AttributeFields;
        AllFields;
        pindex;
        aindex;
        Comparison;
        Results;
        drivingcycle;
        depotparameters;
        VehicleConcepts;
        Vehicle_parameters;
        barblocks;
        AT;
        PropertyNames={['Service' newline 'Performance'],'Accessibility','Comfort','Functionality',...%'Luxury',...
            'Safety',['Longitudinal ' newline 'Dynamics'],'Costs','Environment'};
        OptVariables={'Number of Decks','Interior Layout','Length','Width','Height','Wheelbase','Seat Pitch',...
            'Seat Width','Standing Space','Powertrain Topology','Total Power','Power Split','Gear ratio',...
            'Battery Capacity','Door Width','Wheelchair Zones'}';
        OptLB={1,1,4500,2550,2800,1500,750,500,0.2,1,50,1,1,40,1600,1}';
        OptUB={1,1,13500,2550,2800,6000,950,500,0.2,1,650,1,30,450,1600,1}';
        OptEnable={'Yes','Yes','Yes','Yes','Yes','Yes','Yes','Yes','Yes','Yes','Yes','Yes','Yes','Yes','Yes','Yes'}';
        OptType={2,2,2,2,2,2,2,2,1,2,2,1,2,2,2,2}';
        Simdata;
        Data;
        Stopdata;
       
    end
    
    methods (Access = private)
        
        function updateroutelist(app)
            x=app.Services;
            varlist=[];
            for i=1:size(app.Services,1)
                
                varlist=[varlist;{strcat('Route ',num2str(i))}];
            end
            app.RoutesListBox.Items=varlist;
            
        end
        
        
        function plotproperties(app,psize,comparators,handle,targets,LevelNum)
            n=psize; % number of properties
            m=comparators; % number of simultaneous comparisons
            R=targets;
            R=[R R(:,1)];
            % Plot property targets
            [Theta,M]=meshgrid(2*pi/n*[0:n]+pi/n,ones(1,size(R,1)));
            X=R.*sin(Theta);
            Y=R.*cos(Theta);
            cla(handle)
            plot(handle,X',Y','LineWidth',2);
            % draw axis
            MAXAXIS=10;
            axis(handle,[-MAXAXIS MAXAXIS -MAXAXIS MAXAXIS]);
            axis(handle,'equal')


            AxisR=linspace(0,10,LevelNum);
            %                 for i=6:LevelNum
            %                     text(app.PropertiesPlot,AxisR(i)*sin(pi/n)*1.05,AxisR(i)*cos(pi/n)*1.05,num2str(AxisR(i),2),'FontSize',12,'FontWeight','bold')
            %                 end
            [M,AxisR]=meshgrid(ones(1,n),AxisR);
            AxisR=[AxisR AxisR(:,1)];
            [AxisTheta,M]=meshgrid(2*pi/n*[0:n]+pi/n,ones(1,size(AxisR,1)));
            AxisX=AxisR.*sin(AxisTheta);
            AxisY=AxisR.*cos(AxisTheta);
            hold(handle,'on')
            plot(handle,AxisX,AxisY,'k')%,':k')
            plot(handle,AxisX',AxisY','k')%,':k')
            hold(handle,'on')
            
            %                 % plot names of properties
            %                 LableTheta=2*pi/n*[0:n-1]+pi/n;
            %                 LableR=MAXAXIS;
            %                 LableX=LableR.*sin(LableTheta)*1.25;
            %                 LableY=LableR.*cos(LableTheta)*1.25;
            %                 Lable=app.PropertyFields;
            %                 for i=1:n
            %                     if ~sum(strcmpi({'' },Lable(i)))
            %                         text(app.PropertiesPlot,LableX(i), LableY(i),sprintf(string(Lable(i))), 'FontSize',12,'FontWeight','bold','HorizontalAlignment','center','Rotation',0)
            %                     end
            %                 end
            
            
        end
        
        function loadresults(app)
            % Update Vehicle Specification
            app.LengthmmEditField.Value=app.Results.vehicle.Body.length;
            app.WidthmmEditField.Value=app.Results.vehicle.Body.width;
            app.HeightmmEditField.Value=app.Results.vehicle.Body.height;
            app.WheelbasemmEditField_2.Value=app.Results.vehicle.Body.wheelbase;
            app.CurbMasskgEditField.Value=app.Results.vehicle.Unladenmass;
            app.GrossMasskgEditField.Value=app.Results.vehicle.Grossvehiclemass;
            app.PassengerCapacityEditField.Value=app.Results.vehicle.Passengercapacity;
            app.SeatingCapacityEditField.Value=app.Results.vehicle.Interior.numberseats;
            app.StandingCapacityEditField.Value=app.Results.vehicle.Interior.standingpassengers;
            app.WheelchairZonesEditField.Value=app.Results.vehicle.Interior.wheelchairzones;
            app.TopSpeedkmphEditField.Value=65; %app.Results.vehicle.Properties.TopSpeed.Value;
            app.GradeabilityEditField.Value=15;
            app.RangeEditField.Value=app.Results.vehicle.Battery.capacity/app.Results.vehicle.Energyconsumption;
            app.TurningRadiusmEditField.Value=20;
            app.MotorPowerkWEditField.Value =app.Results.vehicle.Powertrain.totalpower;
            app.BatteryCapacitykWhEditField_2.Value=app.Results.vehicle.Battery.capacity;
            
            
            % Update Timetable Table
            app.TimetableTable.Data=table(app.Results.timetable(:,1),...
                duration(hours(app.Results.timetable(:,3)),'format','hh:mm'),app.Results.timetable(:,2),duration(hours(app.Results.timetable(:,4)),'format','hh:mm'),app.Results.timetable(:,end),app.Results.timetable(:,end-2));
            app.RoutesDropDown.Items=app.RoutesListBox.Items;
            % scheduled trips vs time
            histogram( app.TripsFrequencyAxes,floor(app.Results.timetable(:,3)))
            xlabel(app.TripsFrequencyAxes,'Time')
            ylabel(app.TripsFrequencyAxes,'Scheduled Trips')
            app.TotalTripsEditField.Value=size(app.Results.timetable,1);
            
            
            % Update Passengers
            waitingtime=floor(((app.Results.passengers(:,2)-app.Results.passengers(:,3))*60));
            traveltime=floor(((app.Results.passengers(:,1)-app.Results.passengers(:,3))*60));
            app.PassengerTable.Data=table(duration(hours(app.Results.passengers(:,3)),'format','hh:mm'),...
                duration(hours(app.Results.passengers(:,2)),'format','hh:mm'),waitingtime,traveltime);
            app.MeanWaitingTimeEditField.Value=mean(waitingtime);
            app.MeanTravelTimeEditField.Value=mean(traveltime);
            histogram( app.WaitingTimeAxes,waitingtime)
            %arrival time,boarding time, waiting time, travel time, route
            
            % Update TCO figures and labels
            
            app.OverallFleetCostinSGDEditField.Value=app.Results.TCO.overallcost;
            app.TotalCostofOwnershipinSGDEditField.Value=app.Results.TCO.total;
            app.AcquisitionCostinSGDEditField.Value=app.Results.vehicle.Acquisitioncost;
            app.TotalCostperkmEditField.Value=app.Results.TCO.TCO_km;
            app.TotalCostperpassengerkmEditField.Value=app.Results.TCO.passengerkm;
            plotTCO(app)
            
            % Update Vehicle blocks
            app.VehicleNumberDropDown.Visible='on';
            app.VehicleNumberDropDown.Items=[string(num2str([1:app.Results.fleetsize]'))];
            % plot block for vehicle 1
            app.FleetSizeEditField.Value=app.Results.fleetsize;
            plotblock(app,app.Results.blocks(:,1),1);
            plotvehicletrips(app,app.Results.blocks(:,1));
            
            % Update LCA
            app.TotalFleetGHGEmissionsEditField.Value=sum(app.Results.vehicle.LCAemissions.CO2)/1000000; % per vehicle
            app.GHGEmissionsperkmEditField.Value=app.Results.gCO2_km;
            app.GHGEmissionsperpassengerkmEditField.Value=app.Results.gCO2_passengerkm;
            plotLCA(app)
            
            app.ListBox.Items=strcat('Vehicle_', string(ones(app.Results.fleetsize,1).*(1:app.Results.fleetsize)'));
            app.ListBox.ItemsData=[1:app.Results.fleetsize]';
            app.ListBox.Multiselect='on'
            load('Inputs/SGMap.mat')
            mapshow(app.VisAxes,SGMap,'Facecolor',[1 1 1])
            axis(app.VisAxes,'equal')
            app.ListBox.Value=1;
%              for i=1:size(app.Services,1)
%                 r=app.Services(i);
%                 r=r{:};
%                 hold(app.VisAxes,'on')
%                 plot(app.VisAxes,r.stopcoords(:,1),r.stopcoords(:,2),'--' ...
%                     ,'LineWidth',0.5)
%                 
%             end
        end
        
        function plotblock(app,block,vehicleno)
            n=1;
            cla(app.VehicleBlocksAxes)
            delete(app.barblocks)
            block_i=block;
            [a,b]=(sortrows(([(reshape(block_i{6},2,[])) (reshape(block_i{7},2,[])) (reshape(block_i{8},2,[]))])',1));
            state=[(reshape(block_i{6},2,[]))*0+1 (reshape(block_i{7},2,[]))*0+2 (reshape(block_i{8},2,[]))*0+3];%state
            state=[0 state(1,b)];
            %y=zeros(n+1,size(state,2));
            y=zeros(size(block_i,2),size(state,2));
            y_1=[block_i{6}(1) diff(sortrows(([(reshape(block_i{6},2,[])) (reshape(block_i{7},2,[])) (reshape(block_i{8},2,[]))])',1)')];
            y(n,:)=y_1;
            x=1:size(y,1);
            x=[x;nan];
            y=[y;nan(1,size(y,2))];
            app.barblocks=barh(app.VehicleBlocksAxes,x,y,'stacked');
            set(app.barblocks,'BarWidth',1);
            set(app.barblocks(1),'visible','off');
            set(app.barblocks(find(state==2)),'facecolor','blue');
            set(app.barblocks(find(state==3)),'facecolor','red');
            set(app.barblocks(find(state==1)),'facecolor','green');
            %l=legend(H,'Deadheading','Service Trip','Waiting');
            app.VehicleBlocksAxes.Color=[0.941 0.941 0.941];
            xlabel(app.VehicleBlocksAxes,'Time');
            vn=num2str(vehicleno);
            ylabel(app.VehicleBlocksAxes,strcat('Vehicle ',vn));
            app.NumberofServedTripsEditField.Value= sum(state==2);
            
            % legend({'Deadheading','Service Trip','Waiting'},'Color',{'g','b','r'})
            % update daily km driven and plot tasks for vehicle 1
            hold(app.VehicleBlocksAxes,'on');
            yyaxis(app.VehicleBlocksAxes,'right');
            plot(app.VehicleBlocksAxes,block_i{13, 1} ,block_i{14, 1},'LineWidth',2,'Color','k');
            ylim(app.VehicleBlocksAxes,[0 1]);
            app.VehicleBlocksAxes.YColor='red';
            ylabel(app.VehicleBlocksAxes,'SOC');
            
            app.TotalDistanceDrivenkmdayEditField.Value=app.Results.blocks{4, vehicleno};
            % add number of trips dropdown and plot occupancy for trip 1
            app.TripNumberDropDown.Items=[string(num2str([1:app.NumberofServedTripsEditField.Value]'))];
            occupancyplot(app,block,1)
            plotvehicletrips(app,block)
            hold(app.VehicleBlocksAxes,'off')
        end
        
        function plotTCO(app)
            
            bar(app.AcquisitionCostsAxes,[app.Results.TCO.year0/100000 ...
                app.Results.TCO.cleaning/100000 app.Results.TCO.maintenance/100000 ...
                app.Results.TCO.EOL/100000 app.Results.TCO.energy/100000 app.Results.TCO.personnel/100000 ...
                app.Results.TCO.batteryreplacement/100000 app.Results.TCO.insurance/100000 app.Results.TCO.roadtax/100000; nan(1,9)],'stacked')
            legend(app.AcquisitionCostsAxes,'Acquisition','Cleaning','Maintenance','EOL','Energy','Personnel','Battery Replacement','Insurance','Road Tax')
            xlabel(app.AcquisitionCostsAxes,'Vehicle')
            ylabel(app.AcquisitionCostsAxes,'Total Costs in 100000 SGD')
            colormap(app.AcquisitionCostsAxes,hsv)
            
            bar(app.TCOAxes,[100*app.Results.TCO.year0/app.Results.TCO.total ...
                100*app.Results.TCO.cleaning/app.Results.TCO.total 100*app.Results.TCO.maintenance/app.Results.TCO.total ...
                100*app.Results.TCO.EOL/app.Results.TCO.total 100*app.Results.TCO.energy/app.Results.TCO.total 100*app.Results.TCO.personnel/app.Results.TCO.total ...
                100*app.Results.TCO.batteryreplacement/app.Results.TCO.total 100*app.Results.TCO.insurance/app.Results.TCO.total 100*app.Results.TCO.roadtax/app.Results.TCO.total; nan(1,9)],'stacked')
            legend(app.TCOAxes,'Acquisition','Cleaning','Maintenance','EOL','Energy','Personnel','Battery Replacement','Insurance','Road Tax')
            xlabel(app.TCOAxes,'Vehicle')
            ylabel(app.TCOAxes,'Percentage Share of TCO')
            colormap(app.TCOAxes,jet)
            
            plot(app.TCOPassAxes,1:app.Results.vehicle.Lifetime,cumsum(app.Results.TCO.yearly.total(2:end))/100000,'LineWidth',2)
            ylabel(app.TCOPassAxes,'Cumulative Cost in 100000 SGD')
            xlabel(app.TCOPassAxes,'Year')
            
        end
        
        function occupancyplot(app,block,tripnum)
            trips=block{1,1};
            trips=trips((2:end-1));
            tripaddress=app.Results.timetable(trips(tripnum),:);
            routedata=app.Results.lines(tripaddress(1,end)).passengersonboard;
            if tripaddress(:,6)==1
                occupancy=app.Results.lines(tripaddress(1,end)).passengersonboard.direction1(tripaddress(:,7),:);
            else
                occupancy=app.Results.lines(tripaddress(1,end)).passengersonboard.direction2(tripaddress(:,7),:);
            end
            stairs(app.VehicleOccupancyAxes,[1 1:size(occupancy,2)],[0 occupancy],'LineWidth',2)
            xlabel(app.VehicleOccupancyAxes,'Stop Number')
            ylabel(app.VehicleOccupancyAxes,'Passengers Onboard')
            app.MeanPassengerOccupancyEditField.Value=floor(mean(occupancy));
            
        end
        
        function plotvehicletrips(app,block)
            trips=block{1,1};
            trips=trips((2:end-1));
            tripaddress=app.Results.timetable(trips,:);
            hold(app.TimeSpacePlotAxes,'off')
            % plot trip arcs
            for i=1:size(tripaddress,1) % plot trip arcs
                h1= plot(app.TimeSpacePlotAxes,tripaddress(i,3:4),tripaddress(i,1:2),'k','LineWidth',2);
                hold(app.TimeSpacePlotAxes,'on')
            end
            % plot pull out arcs
            
            h2=plot(app.TimeSpacePlotAxes,block{2,1}(1:2),[0 block{5,1}(2)],'g:','LineWidth',2);
            
            % pull in arcs
            
            h3=plot(app.TimeSpacePlotAxes,block{2,1}(end-1:end),[block{5,1}(end-1) 0],'r:','LineWidth',2);


            % plot deadheading and waiting arcs
            ndh=1;
            nw=1;
            for i=1:size(tripaddress,1)-1
                if tripaddress(i,2)==tripaddress(i+1,1)
                    wait(nw,1)=tripaddress(i,2);
                    wait(nw,2)=tripaddress(i,4);
                    wait(nw,3)=tripaddress(i+1,3);
                    nw=nw+1;
                else
                    DH(ndh,1)=tripaddress(i,2);
                    DH(ndh,2)=tripaddress(i+1,1);
                    DH(ndh,3)=tripaddress(i,4);
                    DH(ndh,4)=tripaddress(i+1,3);
                    ndh=ndh+1;
                end
            end
            % plot deadhead arcs
            % check if no deadhead arcs exist
            if exist('DH')~=0
                dh_times=reshape(block{6, 1},2,size(block{6, 1},2)/2)';
                dh_times=dh_times(2:end-1,:);
                % Additional waiting arcs
                DH_wait=[DH(:,2) dh_times(:,2) DH(:,4)];
                wait=[wait;DH_wait];
                for i=1:size(wait,1) % plot waiting arcs
                    h4= plot(app.TimeSpacePlotAxes,wait(i,2:3),[wait(i,1) wait(i,1)],'m-.','LineWidth',2);
                    hold(app.TimeSpacePlotAxes,'on')
                end
                
                for i=1:size(DH,1) % plot waiting arcs
                    h5= plot(app.TimeSpacePlotAxes,dh_times(i,:),DH(i,1:2),'b--','LineWidth',2);
                    hold(app.TimeSpacePlotAxes,'on')
                end
                
            else
                DH_wait=[];
                h5=line([],[]);
                
            end
            
            wait=[wait;DH_wait];
            for i=1:size(wait,1) % plot waiting arcs
                h4= plot(app.TimeSpacePlotAxes,wait(i,2:3),[wait(i,1) wait(i,1)],'m-.','LineWidth',2);
                hold(app.TimeSpacePlotAxes,'on')
            end
            
            legend([h1 h2 h3 h4 h5],'Trips','Pull Out','Pull In','Waiting','Deadhead')
            
        end
        
        function plotLCA(app)
            % app.Results.vehicle
            cla(app.GHGEmissionsAxes)
            production=app.Results.vehicle.Productionemissions.CO2;
            distribution=app.Results.vehicle.Distributionemissions.CO2;
            EOL=app.Results.vehicle.EOLemissions.CO2;
            WTT=mean(app.Results.vehicle.WTTemissions);
            TTW=(app.Results.vehicle.TTWemissions);
            if isempty(TTW)
                TTW=0;
            end
            barh(app.GHGEmissionsAxes,[1 ;2],[production/1000 distribution/1000 WTT/1000 TTW/1000;0 0 0 0],'stacked')
            hold(app.GHGEmissionsAxes,'on')
            barh(app.GHGEmissionsAxes,[1 ;2],[EOL/1000;0],'stacked')
            legend(app.GHGEmissionsAxes,'Production','Distribution','Well-to-Tank','Tank-to-Wheel','End-Of-Life')
            ylabel(app.GHGEmissionsAxes,'Vehicle')
            xlabel(app.GHGEmissionsAxes,'GHG Emissions in Tons of CO2eq')
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, map_sg)
            app.AttributeTargets={app.ServicePerformanceTargets;
                app.AccessibilityTargets;
                app.ComfortTargets;
                app.FunctionalityTargets;
             %   app.LuxuryTargets;
                app.SafetyTargets;
                app.LongitudinalDynamicsTargets;
                app.CostsTargets;
                app.EnvironmentTargets;
                };
            
            app.AttributeFields={app.ServicePerformanceFields;
                app.AccessibilityFields;
                app.ComfortFields;
                app.FunctionalityFields;
              %  app.LuxuryFields;
                app.SafetyFields;
                app.LongitudinalDynamicsFields;
                app.CostsFields;
                app.EnvironmentFields;
                };
            app.AllFields=strings(size(app.PropertyFields,2),size(app.AttributeFields,1));
            app.AllFields(:,1)=app.PropertyFields';
            
            for i=1:size(app.PropertyFields,2)
                app.AllFields(i,2: size(app.AttributeFields{i,1},2)+1)=app.AttributeFields{i};
                
            end
            
            app.OptInputTable.Data=table(app.OptVariables,app.OptLB,app.OptUB,app.OptEnable);
            
            addpath(genpath(pwd));
            % mapshow( app.UIAxes,shaperead(strcat(pwd,'\Inputs\SG.shp')));
            load(fullfile(pwd,'Inputs',(filesep),'SGMap.mat'));
            mapshow(app.UIAxes,SGMap);

            app.UIAxes.Visible='off';
            disableDefaultInteractivity(app.UIAxes);
            app.VisAxes.Visible='off';
            app.AT=AEVtoolkit();
            %% general properties
            app.SelectedProperty.ValueChangedFcn(app,1);
            app.SelectedAttribute.ValueChangedFcn(app,1);
            %disableDefaultInteractivity(VehicleBlocksAxes)
            %plotproperties(app,size(app.PropertyFields,2),1,app.PropertiesPlot,app.Targets,6)
            %app.AT.plotproperties(app.PropertiesPlot,10,app.Targets,6,app.PropertyNames)
            app.AT.plotProperties(app.PropertyAxes,size(app.PropertyNames,2),app.Targets,6,app.PropertyNames);
            app.PropertyAxes.Visible='off';
            disableDefaultInteractivity(app.PropertyAxes);
            
            
            app.AttributeAxes.Visible='off';
            disableDefaultInteractivity(app.AttributeAxes);
            
            app.AT.plotProperties(app.PropertyAxes_2,size(app.PropertyNames,2),app.Targets,6,app.PropertyNames);
            app.PropertyAxes_2.Visible='off';
            disableDefaultInteractivity(app.PropertyAxes_2);
            
            
            app.AttributeAxes_2.Visible='off';
            disableDefaultInteractivity(app.AttributeAxes_2);
            
            %app.AT.plotproperties(app.PropertyAxes,10,app.Targets,6,app.PropertyNames)
            
            disableDefaultInteractivity(app.ValueFunctionAxes);
            %app.AT.plotValueFunction(app.ValueFunctionAxes,0.5,70,40,120,'Speed in km/h')
           
            
            app.ParetoDesigns.CellSelectionCallback;
            
            app.VehicleConcepts={}; %cell(1,3);
            
            app.XaxismaxEditField.Value=app.AT.xu(app.pindex,app.aindex);
            app.XaxisminEditField.Value=app.AT.xl(app.pindex,app.aindex);
            
           %%
            load(fullfile(pwd,'Inputs',(filesep),'DHmatrix.mat'));
            depot_parameters.depotcoordinates=[103.966986,1.36885];
            depot_parameters.DH=DHmatrix;
            depot_parameters.chargingpower=app.ChargerPowerkWEditField.Value;
            app.depotparameters=depot_parameters;
            load(fullfile(pwd,'Inputs',(filesep),'DrivingCycles',filesep,'drivingcycle_brt.mat'));
            driving_cycle=DRIVING_CYCLE;
            app.drivingcycle=driving_cycle;
            app.TimeFrameSlider.Visible='off';
            app.PackageAxes.Visible='off';
        end

        % Menu selected function: ImportRoutesMenu
        function getroute(app, event)
            [pathname,dirname] = uigetfile();
            fullpath = fullfile(dirname,pathname);
            datacell =  struct2cell(load(fullpath));
           try
            app.Services=[app.Services;datacell];
            updateroutelist(app);
           catch
              disp('inconsistent inputs, clearing all inputs');
           end
           
        end

        % Value changed function: PlotRoutesCheckBox
        function PlotRoutesCheckBoxValueChanged(app, event)
           % value = app.PlotRoutesCheckBox.Value;
            val=app.RoutesListBox.Items';
            for i=1:size(val,1)
                r=app.Services(i);
                r=r{:};
                hold(app.UIAxes,'on')
                scatter(app.UIAxes,r.stopcoords(:,1),r.stopcoords(:,2),10,'filled');
                
            end
            
        end

        % Callback function
        function ServicePerfomanceCheckBoxValueChanged(app, event)
            value = app.ServicePerfomanceCheckBox.Value;
            if value>0 % change the values of other checkbox to zero
                app.EnvironmentCheckBox.Value=0;
                app.AccessibilityCheckBox_2.Value=0;
                app.ComfortCheckBox_2.Value=0;
                app.DynamicsCheckBox.Value=0;
                app.CostsCheckBox.Value=0;
                app.SafetyCheckBox1.Value=0;
            end
            
            
        end

        % Callback function
        function SliderValueChanged(app, event)
            
        end

        % Callback function
        function EnvironmentCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function CostsCheckBoxValueChanged(app, event)
          
        end

        % Callback function
        function DynamicsCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function ComfortCheckBox_2ValueChanged(app, event)
           
        end

        % Callback function
        function AccessibilityCheckBox_2ValueChanged(app, event)
          
        end

        % Callback function
        function SafetyCheckBox1ValueChanged(app, event)
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            % Delete UIFigure when app is deleted
            delete(app.AEVToolUIFigure)
        end

        % Callback function: PowertrainTopologyDropDown, 
        % SaveConceptButton
        function SaveConceptButtonPushed(app, event)
            % Check if inputs are valid
            
            %else
            name=inputdlg('Save Vehicle');
            app.VehicleConceptsListBox.Items=[app.VehicleConceptsListBox.Items name];
            if string(app.InteriorLayoutDropDown.Value)=='Coach Layout'
                interior=1;
            elseif string(app.InteriorLayoutDropDown.Value)=='Urban Layout 1'
                interior=2;
            elseif string(app.InteriorLayoutDropDown.Value)=='Urban Layout 2'
                interior=3;
            end
            if string(app.PowertrainTopologyDropDown.Value)=='Single Rear Wheel Drive'
                powertraintopology=1;
            elseif string(app.PowertrainTopologyDropDown.Value)=='Dual Rear Wheel Drive'
                powertraintopology=2;
            elseif string(app.PowertrainTopologyDropDown.Value)=='Four Wheel Drive 2 Motors'
                powertraintopology=3;
            elseif string(app.PowertrainTopologyDropDown.Value)=='Four Wheel Drive 4 Motors'
                powertraintopology=4;
            end
            %             VehicleConcepts={name,[app.SingleDeckerCheckBox.Value+(2*app.SingleDeckerCheckBox.Value), ...
            %                 interior,app.VehicleLengthmmEditField.Value,app.VehicleWidthmmEditField.Value,app.VehicleHeightmmEditField.Value,...
            %                 app.WheelbasemmEditField.Value,app.LegroommmEditField.Value,app.SeatWidthmmEditField.Value,app.StandingSpaceEditField.Value,...
            %                 app.AisleWidthmmEditField.Value,app.AirConditioningCheckBox.Value,powertraintopology,app.TotalPowerkWEditField.Value,app.PowerSplitRatioEditField.Value,...
            %                 app.NumberofGearsDropDown.Value,app.BatteryCapacitykWhEditField.Value]};
            app.Vehicle_parameters={app.SingleDeckerCheckBox.Value+(2*app.DoubleDeckerCheckBox.Value), ...
                interior,app.VehicleLengthmmEditField.Value,app.VehicleWidthmmEditField.Value,app.VehicleHeightmmEditField.Value,...
                app.WheelbasemmEditField.Value,app.LegroommmEditField.Value,app.SeatWidthmmEditField.Value,app.StandingSpaceEditField.Value,...
                powertraintopology,app.TotalPowerkWEditField.Value,app.PowerSplitRatioEditField.Value,...
                double(string(app.NumberofGearsDropDown.Value)),app.BatteryCapacitykWhEditField.Value};
            %{1,1,12000,2550,2800,6000,300,400,0.25,1,300,0,1,200};
            %app.VehicleConceptsListBox.ItemsData=[app.VehicleConceptsListBox.ItemsData {app.Vehicle_parameters}];
             app.VehicleConcepts=[app.VehicleConcepts;{name{:} app.Vehicle_parameters []}];
             vehicle_parameters=app.Vehicle_parameters;
             save(char(fullfile(pwd,(filesep),'Vehicles',name)),'vehicle_parameters')
        end

        % Value changed function: VehicleConceptsListBox
        function VehicleConceptsListBoxValueChanged(app, event)
                        value = app.VehicleConceptsListBox.Value;
        end

        % Button pushed function: EvaluatePropertiesButton
        function EvaluatePropertiesButtonPushed(app, event)
            if isempty(app.VehicleConceptsListBox.Value)
                msgbox('Please select a vehicle from the list','Error');
            else
                f=waitbar(0,'Evaluating Vehicle Concept');
                vehicle_parameters=app.VehicleConcepts(find(strcmp(app.VehicleConcepts,app.VehicleConceptsListBox.Value)),2);%cell2mat(app.Vehicle_parameters); %{1,1,12000,2550,2800,6000,300,400,0.25,1,300,0,1,200};
                vehicle_parameters=cell2mat(vehicle_parameters{:});
                app.Results=depot(app.Services,app.depotparameters,vehicle_parameters,app.drivingcycle,f);
                [fitness(2),app.Results.property]=sumproperty(app.AT,app.Results.property);
                f=app.Results.dialogbar;
                f=waitbar(1,f,'Loading Results');
                [app.Simdata,app.Stopdata]=getsimdata(app.AT,app.Results);
                loadresults(app)
                delete(f)
                app.VehicleConcepts(find(strcmp(app.VehicleConcepts,app.VehicleConceptsListBox.Value)),3)={app.Results};
                app.TabGroup.SelectedTab=app.ResultsTab;
                app.TabGroup3.SelectedTab=app.PropertyComparisonTab;
                app.SelectedProperty_2.ValueChangedFcn(app,1);
                app.SelectedAttribute_3.ValueChangedFcn(app,1);
                
            end
        end

        % Value changed function: VehicleNumberDropDown
        function VehicleNumberDropDownValueChanged(app, event)
            value = app.VehicleNumberDropDown.Value;
            plotblock(app,app.Results.blocks(:,str2num(value)),str2num(value));
            
            
        end

        % Value changed function: TripNumberDropDown
        function TripNumberDropDownValueChanged(app, event)
            value = app.TripNumberDropDown.Value;
            block=app.Results.blocks(:,str2num(app.VehicleNumberDropDown.Value));
            occupancyplot(app,block,str2num(value))
        end

        % Value changed function: SelectedProperty
        function SelectedPropertyValueChanged(app, event)
            Value = string(app.SelectedProperty.Value);
            % update slider value to property value
            
            [app.pindex,~]=find(contains(app.AllFields(:,1),Value));
            app.AT.plotProperties(app.AttributeAxes,size(app.AttributeFields{app.pindex},2),app.AttributeTargets{app.pindex},6,...
                app.AttributeFields{app.pindex});
            app.SelectedAttribute.Items=app.AttributeFields{app.pindex};
            app.SelectedAttribute.ValueChangedFcn(app,1);
            app.PropertySlider.Value=app.Targets(app.pindex);
            
        end

        % Value changed function: SelectedAttribute
        function SelectedAttributeValueChanged(app, event)
            Value = string(app.SelectedAttribute.Value);
            property=(string(app.SelectedProperty.Value));
            [app.pindex,app.aindex]=find(contains(app.AllFields,Value));
            app.aindex=app.aindex-1;
            app.AT.plotValueFunction(app.ValueFunctionAxes,app.AT.slope(app.pindex,app.aindex)...
                ,app.AT.inflexion(app.pindex,app.aindex),app.AT.xl(app.pindex,app.aindex),app.AT.xu(app.pindex,app.aindex),...
                app.AT.xname(app.pindex,app.aindex))
            app.XaxismaxEditField.Value=app.AT.xu(app.pindex,app.aindex);
            app.XaxisminEditField.Value=app.AT.xl(app.pindex,app.aindex);
            app.InflexionPointSlider.Limits=[app.XaxisminEditField.Value app.XaxismaxEditField.Value];
            app.InflexionPointSlider.Value=app.AT.inflexion(app.pindex,app.aindex);
            app.SlopeSlider.Value=app.AT.slope(app.pindex,app.aindex);
            app.AttributeSlider.Value=app.AttributeTargets{app.pindex}(app.aindex);
            
        end

        % Value changed function: SlopeSlider
        function SlopeSliderValueChanged(app, event)
            value = app.SlopeSlider.Value;
            app.AT.slope(app.pindex,app.aindex)=value;
            app.AT.plotValueFunction(app.ValueFunctionAxes,app.AT.slope(app.pindex,app.aindex)...
                ,app.AT.inflexion(app.pindex,app.aindex),app.AT.xl(app.pindex,app.aindex),app.AT.xu(app.pindex,app.aindex),...
                app.AT.xname(app.pindex,app.aindex));
        end

        % Value changed function: InflexionPointSlider
        function InflexionPointSliderValueChanged(app, event)
            value = app.InflexionPointSlider.Value;
            app.AT.inflexion(app.pindex,app.aindex)=value;
            app.AT.plotValueFunction(app.ValueFunctionAxes,app.AT.slope(app.pindex,app.aindex)...
                ,app.AT.inflexion(app.pindex,app.aindex),app.AT.xl(app.pindex,app.aindex),app.AT.xu(app.pindex,app.aindex),...
                app.AT.xname(app.pindex,app.aindex));
        end

        % Value changed function: XaxisminEditField
        function XaxisminEditFieldValueChanged(app, event)
            value = app.XaxisminEditField.Value;
            app.AT.xl(app.pindex,app.aindex)=value;
            app.AT.plotValueFunction(app.ValueFunctionAxes,app.AT.slope(app.pindex,app.aindex)...
                ,app.AT.inflexion(app.pindex,app.aindex),app.AT.xl(app.pindex,app.aindex),app.AT.xu(app.pindex,app.aindex),...
                app.AT.xname(app.pindex,app.aindex));
        end

        % Value changed function: XaxismaxEditField
        function XaxismaxEditFieldValueChanged(app, event)
            value = app.XaxismaxEditField.Value;
            app.AT.xu(app.pindex,app.aindex)=value;
            app.AT.plotValueFunction(app.ValueFunctionAxes,app.AT.slope(app.pindex,app.aindex)...
                ,app.AT.inflexion(app.pindex,app.aindex),app.AT.xl(app.pindex,app.aindex),app.AT.xu(app.pindex,app.aindex),...
                app.AT.xname(app.pindex,app.aindex));
        end

        % Value changed function: PropertySlider
        function PropertySliderValueChanged(app, event)
            value = app.PropertySlider.Value;
            app.Targets(app.pindex)=value;
            app.AT.plotProperties(app.PropertyAxes,size(app.PropertyNames,2),app.Targets,6,app.PropertyNames)
        end

        % Value changed function: AttributeSlider
        function AttributeSliderValueChanged(app, event)
            value = app.AttributeSlider.Value;
            app.AttributeTargets{app.pindex}(app.aindex)=value;
            app.AT.plotProperties(app.AttributeAxes,size(app.AttributeTargets{app.pindex},2),app.AttributeTargets{app.pindex},6,...
                app.AttributeFields{app.pindex});
            
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ResultsTab;
            app.TabGroup3.SelectedTab=app.ParetoFrontTab;
            pause(0.1)
            app.GenerationListBox.Items=string(linspace(1,100)');
            app.OptLB=table2cell(app.OptInputTable.Data(:,2));
            app.OptUB=table2cell(app.OptInputTable.Data(:,3));
            OptVar=[app.OptVariables app.OptLB app.OptUB app.OptType];
            global result
            result=app.AT.optimizeConcept(app,OptVar);
            
        end

        % Button pushed function: StopOptimisationButton
        function StopOptimisationButtonPushed(app, event)
           global STOP_NSGA;
           STOP_NSGA=1;
        end

        % Value changed function: SelectedProperty_2
        function SelectedProperty_2ValueChanged(app, event)
            value = app.SelectedProperty_2.Value;
            cla(app.AttributeAxes_2)

             [app.pindex,~]=find(contains(app.AllFields(:,1),value));
            app.AT.plotProperties(app.AttributeAxes_2,size(app.AttributeFields{app.pindex},2),app.AttributeTargets{app.pindex},6,...
                app.AttributeFields{app.pindex})
            results=app.VehicleConcepts(find(strcmp(app.VehicleConcepts,app.VehicleConceptsListBox.Value)),3);
            results=results{:};
            
            app.AT.plotProperties(app.AttributeAxes_2,size(app.AttributeFields{app.pindex},2),results.property{app.pindex,2},6,...
                app.AttributeFields{app.pindex})
            app.SelectedAttribute_3.Items=app.AttributeFields{app.pindex};
            app.SelectedAttribute_3.ValueChangedFcn(app,1);
        
        end

        % Value changed function: SelectedAttribute_3
        function SelectedAttribute_3ValueChanged(app, event)
            value = app.SelectedAttribute_3.Value;
              property=(string(app.SelectedProperty_2.Value));
            [app.pindex,app.aindex]=find(contains(app.AllFields,value))
            app.aindex=app.aindex-1;
            app.AT.plotValueFunction(app.ValueFunctionAxes_2,app.AT.slope(app.pindex,app.aindex)...
                ,app.AT.inflexion(app.pindex,app.aindex),app.AT.xl(app.pindex,app.aindex),app.AT.xu(app.pindex,app.aindex),...
                app.AT.xname(app.pindex,app.aindex))
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            vehicles=value';
            app.Data=app.Simdata(ismember(app.Simdata(:,11),vehicles),:);
            app.TimeFrameSlider.Limits=[1,size(app.Data,1)];
            app.TimeFrameSlider.Visible='on';
            app.TimeFrameSlider.Value=1;
        end

        % Value changed function: TimeFrameSlider
        function TimeFrameSliderValueChanged(app, event)
            value = app.TimeFrameSlider.Value;
            plotVehiclesfunction(app.AT,app.Data,app.ListBox.Value',value, ...
                app.VisAxes,size(app.Services,1),app.Stopdata);
            
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            1
            disp('code me')
        end

        % Button pushed function: GeneratePackageButton, 
        % GenerateVehiclePackageButton
        function GeneratePackageButtonPushed(app, event)
             if isempty(app.VehicleConceptsListBox.Value)
                msgbox('Please select a vehicle from the list','Error');
             else
                  app.TabGroup.SelectedTab=app.ResultsTab;
                  app.TabGroup3.SelectedTab=app.VehicleSpecificationTab;
                  app.TabGroup6.SelectedTab=app.PackageTab;
                vehicle_parameters=app.VehicleConcepts(find(strcmp(app.VehicleConcepts,app.VehicleConceptsListBox.Value)),2);%cell2mat(app.Vehicle_parameters); %{1,1,12000,2550,2800,6000,300,400,0.25,1,300,0,1,200};
                vehicle_parameters=cell2mat(vehicle_parameters{:});
                vehicle=vehicleconcept(vehicle_parameters);
                vehicle.package(app.PackageAxes)
             end
        end

        % Menu selected function: ImportDistanceTimeMatrixMenu
        function ImportDistanceTimeMatrixMenuSelected(app, event)
               [pathname,dirname] = uigetfile();
            fullpath = fullfile(dirname,pathname);
            
           try
           (load(fullpath));
           % load(fullfile(pwd,'Inputs',(filesep),'DHmatrix.mat'));
            %depot_parameters.depotcoordinates=[103.966986,1.36885];
            depot_parameters.DH=DHmatrix;
            depot_parameters.chargingpower=app.ChargerPowerkWEditField.Value;
            app.depotparameters=depot_parameters;
           catch
              disp('inconsistent inputs, clearing all inputs');
           end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AEVToolUIFigure = uifigure('Visible', 'off');
            app.AEVToolUIFigure.Name = 'AEV Tool';
            app.FileMenu = uimenu(app.AEVToolUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportRoutesMenu = uimenu(app.FileMenu);
            app.ImportRoutesMenu.MenuSelectedFcn = createCallbackFcn(app, @getroute, true);
            app.ImportRoutesMenu.Text = 'Import Routes';
            app.ImportDistanceTimeMatrixMenu = uimenu(app.FileMenu);
            app.ImportDistanceTimeMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportDistanceTimeMatrixMenuSelected, true);
            app.ImportDistanceTimeMatrixMenu.Text = 'Import Distance-Time Matrix';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.AEVToolUIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.RoutesListBoxLabel = uilabel(app.InputsTab);
            app.RoutesListBoxLabel.Text = 'Routes';
            app.RoutesListBox = uilistbox(app.InputsTab);
            app.RoutesListBox.Items = {};
            app.RoutesListBox.Value = {};
            app.ClearButton = uibutton(app.InputsTab, 'push');
            app.ClearButton.Text = 'Clear';
            app.PlotRoutesCheckBox = uicheckbox(app.InputsTab);
            app.PlotRoutesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotRoutesCheckBoxValueChanged, true);
            app.PlotRoutesCheckBox.Text = 'Plot Routes';
            app.DepotCoordinatesLabel = uilabel(app.InputsTab);
            app.DepotCoordinatesLabel.Text = 'Depot Coordinates';
            app.LongitudeLabel = uilabel(app.InputsTab);
            app.LongitudeLabel.Text = 'Longitude:';
            app.LongitudeEditField = uieditfield(app.InputsTab, 'numeric');
            app.LongitudeEditField.ValueDisplayFormat = '%.3f';
            app.LongitudeEditField.Value = 103.97;
            app.LattitudeEditFieldLabel = uilabel(app.InputsTab);
            app.LattitudeEditFieldLabel.Text = 'Lattitude:';
            app.LattitudeEditField = uieditfield(app.InputsTab, 'numeric');
            app.LattitudeEditField.ValueDisplayFormat = '%.3f';
            app.LattitudeEditField.Value = 1.369;
            app.TabGroup5 = uitabgroup(app.InputsTab);
            app.ChargingStrategyTab = uitab(app.TabGroup5);
            app.ChargingStrategyTab.Title = 'Charging Strategy';
            app.OvernightChargingSwitchLabel = uilabel(app.ChargingStrategyTab);
            app.OvernightChargingSwitchLabel.Text = 'Overnight Charging';
            app.OvernightChargingSwitch = uiswitch(app.ChargingStrategyTab, 'slider');
            app.TerminalChargingSwitchLabel = uilabel(app.ChargingStrategyTab);
            app.TerminalChargingSwitchLabel.Text = 'Terminal Charging';
            app.TerminalChargingSwitch = uiswitch(app.ChargingStrategyTab, 'slider');
            app.ChargerPowerkWEditFieldLabel = uilabel(app.ChargingStrategyTab);
            app.ChargerPowerkWEditFieldLabel.Text = 'Charger Power (kW)';
            app.ChargerPowerkWEditField = uieditfield(app.ChargingStrategyTab, 'numeric');
            app.ChargerPowerkWEditField.Value = 50;
            app.SchedulingStrategyTab = uitab(app.TabGroup5);
            app.SchedulingStrategyTab.Title = 'Scheduling Strategy';
            app.TimetableStrategyButtonGroup = uibuttongroup(app.SchedulingStrategyTab);
            app.TimetableStrategyButtonGroup.Title = 'Timetable Strategy';
            app.EvenHeadwayButton = uitogglebutton(app.TimetableStrategyButtonGroup);
            app.EvenHeadwayButton.Text = 'Even Headway';
            app.EvenHeadwayButton.Value = true;
            app.EvenLoadButton = uitogglebutton(app.TimetableStrategyButtonGroup);
            app.EvenLoadButton.Text = 'Even Load';
            app.ClocktimetableButton = uitogglebutton(app.TimetableStrategyButtonGroup);
            app.ClocktimetableButton.Text = 'Clock timetable';
            app.LoadPredeterminedTimetableButton = uibutton(app.SchedulingStrategyTab, 'push');
            app.LoadPredeterminedTimetableButton.Text = 'Load Predetermined Timetable';
            app.ElectricityEnergyMixTab = uitab(app.TabGroup5);
            app.ElectricityEnergyMixTab.Title = 'Electricity Energy Mix';
            app.CostConfigurationTab = uitab(app.TabGroup5);
            app.CostConfigurationTab.Title = 'Cost Configuration';
            app.UIAxes = uiaxes(app.InputsTab);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.PropertyTargetsTab = uitab(app.TabGroup);
            app.PropertyTargetsTab.Title = 'Property Targets';
            app.PropertySlider = uislider(app.PropertyTargetsTab);
            app.PropertySlider.Limits = [5 10];
            app.PropertySlider.MajorTicks = [5 6 7 8 9 10];
            app.PropertySlider.MajorTickLabels = {'5', '6', '7', '8', '9', '10'};
            app.PropertySlider.ValueChangedFcn = createCallbackFcn(app, @PropertySliderValueChanged, true);
            app.PropertySlider.MinorTicks = [5.5 6.5 7.5 8.5 9.5];
            app.PropertySlider.Value = 5;
            app.PropertyDropDown_2Label = uilabel(app.PropertyTargetsTab);
            app.PropertyDropDown_2Label.Text = 'Property';
            app.SelectedProperty = uidropdown(app.PropertyTargetsTab);
            app.SelectedProperty.Items = {'Service Performance', 'Accessibility', 'Comfort', 'Functionality', 'Safety', 'Longitudinal Dynamics', 'Costs', 'Environment'};
            app.SelectedProperty.ValueChangedFcn = createCallbackFcn(app, @SelectedPropertyValueChanged, true);
            app.SelectedProperty.Value = 'Service Performance';
            app.AttributeDropDown_2Label = uilabel(app.PropertyTargetsTab);
            app.AttributeDropDown_2Label.Text = 'Attribute';
            app.SelectedAttribute = uidropdown(app.PropertyTargetsTab);
            app.SelectedAttribute.Items = {};
            app.SelectedAttribute.ValueChangedFcn = createCallbackFcn(app, @SelectedAttributeValueChanged, true);
            app.SelectedAttribute.Value = {};
            app.AttributeSlider = uislider(app.PropertyTargetsTab);
            app.AttributeSlider.Limits = [5 10];
            app.AttributeSlider.MajorTicks = [5 6 7 8 9 10];
            app.AttributeSlider.MajorTickLabels = {'5', '6', '7', '8', '9', '10'};
            app.AttributeSlider.ValueChangedFcn = createCallbackFcn(app, @AttributeSliderValueChanged, true);
            app.AttributeSlider.MinorTicks = [5.5 6.5 7.5 8.5 9.5];
            app.AttributeSlider.Value = 5;
            app.Label_2 = uilabel(app.PropertyTargetsTab);
            app.Label_2.Text = {'Define/Modify the target weights for each objective used in '; 'the evaluation of the vehicle concept'};
            app.Label_3 = uilabel(app.PropertyTargetsTab);
            app.Label_3.Text = {'Modify the value function curve by adjusting the technical '; 'characteristic, slope and inflexion point for each attribute'};
            app.SlopeSliderLabel = uilabel(app.PropertyTargetsTab);
            app.SlopeSliderLabel.Text = 'Slope';
            app.SlopeSlider = uislider(app.PropertyTargetsTab);
            app.SlopeSlider.Limits = [-4 4];
            app.SlopeSlider.ValueChangedFcn = createCallbackFcn(app, @SlopeSliderValueChanged, true);
            app.InflexionPointSliderLabel = uilabel(app.PropertyTargetsTab);
            app.InflexionPointSliderLabel.Text = {'Inflexion'; 'Point'};
            app.InflexionPointSlider = uislider(app.PropertyTargetsTab);
            app.InflexionPointSlider.ValueChangedFcn = createCallbackFcn(app, @InflexionPointSliderValueChanged, true);
            app.XaxisminEditFieldLabel = uilabel(app.PropertyTargetsTab);
            app.XaxisminEditFieldLabel.Text = 'X axis min';
            app.XaxisminEditField = uieditfield(app.PropertyTargetsTab, 'numeric');
            app.XaxisminEditField.ValueChangedFcn = createCallbackFcn(app, @XaxisminEditFieldValueChanged, true);
            app.XaxismaxEditFieldLabel = uilabel(app.PropertyTargetsTab);
            app.XaxismaxEditFieldLabel.Text = 'X axis max';
            app.XaxismaxEditField = uieditfield(app.PropertyTargetsTab, 'numeric');
            app.XaxismaxEditField.ValueChangedFcn = createCallbackFcn(app, @XaxismaxEditFieldValueChanged, true);
            app.PropertyAxes = uiaxes(app.PropertyTargetsTab);
            title(app.PropertyAxes, 'Property')
            xlabel(app.PropertyAxes, 'X')
            ylabel(app.PropertyAxes, 'Y')
            app.AttributeAxes = uiaxes(app.PropertyTargetsTab);
            title(app.AttributeAxes, 'Attributes')
            xlabel(app.AttributeAxes, 'X')
            ylabel(app.AttributeAxes, 'Y')
            app.AttributeAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ValueFunctionAxes = uiaxes(app.PropertyTargetsTab);
            title(app.ValueFunctionAxes, 'Value Function')
            xlabel(app.ValueFunctionAxes, 'X')
            ylabel(app.ValueFunctionAxes, 'Y')
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Title = 'Design';
            app.ExplorationFeatureTab = uitabgroup(app.DesignTab);
            app.GeneralTab_2 = uitab(app.ExplorationFeatureTab);
            app.GeneralTab_2.Title = 'General';
            app.EvaluatePropertiesButton = uibutton(app.GeneralTab_2, 'push');
            app.EvaluatePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluatePropertiesButtonPushed, true);
            app.EvaluatePropertiesButton.Text = 'Evaluate Properties';
            app.VehicleConceptsListBoxLabel = uilabel(app.GeneralTab_2);
            app.VehicleConceptsListBoxLabel.Text = 'Vehicle Concepts';
            app.VehicleConceptsListBox = uilistbox(app.GeneralTab_2);
            app.VehicleConceptsListBox.Items = {};
            app.VehicleConceptsListBox.ValueChangedFcn = createCallbackFcn(app, @VehicleConceptsListBoxValueChanged, true);
            app.VehicleConceptsListBox.Value = {};
            app.AddtoComparisonButton = uibutton(app.GeneralTab_2, 'push');
            app.AddtoComparisonButton.Text = 'Add to Comparison';
            app.LoadVehicleButton = uibutton(app.GeneralTab_2, 'push');
            app.LoadVehicleButton.Text = 'Load Vehicle';
            app.OptimizeButton = uibutton(app.GeneralTab_2, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.PopulationSizeEditFieldLabel = uilabel(app.GeneralTab_2);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.PopulationSizeEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.PopulationSizeEditField.Value = 500;
            app.GenerationsEditFieldLabel = uilabel(app.GeneralTab_2);
            app.GenerationsEditFieldLabel.Text = 'Generations';
            app.GenerationsEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.GenerationsEditField.Value = 30;
            app.ParallelPoolCheckBox = uicheckbox(app.GeneralTab_2);
            app.ParallelPoolCheckBox.Text = 'Parallel Pool';
            app.ParallelPoolCheckBox.Value = true;
            app.PoolSizeSpinnerLabel = uilabel(app.GeneralTab_2);
            app.PoolSizeSpinnerLabel.Text = 'Pool Size';
            app.PoolSizeSpinner = uispinner(app.GeneralTab_2);
            app.PoolSizeSpinner.Value = 12;
            app.GeneratePackageButton = uibutton(app.GeneralTab_2, 'push');
            app.GeneratePackageButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePackageButtonPushed, true);
            app.GeneratePackageButton.Text = 'Generate Package';
            app.DefineNewVehicleTab = uitab(app.ExplorationFeatureTab);
            app.DefineNewVehicleTab.Title = 'Define New Vehicle';
            app.ExteriorParametersPanel = uipanel(app.DefineNewVehicleTab);
            app.ExteriorParametersPanel.Title = 'Exterior Parameters';
            app.SingleDeckerCheckBox = uicheckbox(app.ExteriorParametersPanel);
            app.SingleDeckerCheckBox.Text = 'Single Decker';
            app.SingleDeckerCheckBox.Value = true;
            app.DoubleDeckerCheckBox = uicheckbox(app.ExteriorParametersPanel);
            app.DoubleDeckerCheckBox.Text = 'Double Decker';
            app.VehicleLengthmmEditFieldLabel = uilabel(app.ExteriorParametersPanel);
            app.VehicleLengthmmEditFieldLabel.Text = 'Vehicle Length (mm)';
            app.VehicleLengthmmEditField = uieditfield(app.ExteriorParametersPanel, 'numeric');
            app.VehicleLengthmmEditField.Limits = [4000 15000];
            app.VehicleLengthmmEditField.ValueDisplayFormat = '%.0f';
            app.VehicleLengthmmEditField.Value = 12000;
            app.VehicleWidthmmEditFieldLabel = uilabel(app.ExteriorParametersPanel);
            app.VehicleWidthmmEditFieldLabel.Text = 'Vehicle Width (mm)';
            app.VehicleWidthmmEditField = uieditfield(app.ExteriorParametersPanel, 'numeric');
            app.VehicleWidthmmEditField.Limits = [1400 2700];
            app.VehicleWidthmmEditField.Value = 2550;
            app.VehicleHeightmmEditFieldLabel = uilabel(app.ExteriorParametersPanel);
            app.VehicleHeightmmEditFieldLabel.Text = 'Vehicle Height (mm)';
            app.VehicleHeightmmEditField = uieditfield(app.ExteriorParametersPanel, 'numeric');
            app.VehicleHeightmmEditField.Limits = [2000 5000];
            app.VehicleHeightmmEditField.Value = 2800;
            app.WheelbasemmEditFieldLabel = uilabel(app.ExteriorParametersPanel);
            app.WheelbasemmEditFieldLabel.Text = 'Wheelbase (mm)';
            app.WheelbasemmEditField = uieditfield(app.ExteriorParametersPanel, 'numeric');
            app.WheelbasemmEditField.Value = 6000;
            app.InteriorParametersPanel = uipanel(app.DefineNewVehicleTab);
            app.InteriorParametersPanel.Title = 'Interior Parameters';
            app.SeatWidthmmEditFieldLabel = uilabel(app.InteriorParametersPanel);
            app.SeatWidthmmEditFieldLabel.Text = 'Seat Width (mm)';
            app.SeatWidthmmEditField = uieditfield(app.InteriorParametersPanel, 'numeric');
            app.SeatWidthmmEditField.Value = 400;
            app.LegroommmEditFieldLabel = uilabel(app.InteriorParametersPanel);
            app.LegroommmEditFieldLabel.Text = 'Legroom (mm)';
            app.LegroommmEditField = uieditfield(app.InteriorParametersPanel, 'numeric');
            app.LegroommmEditField.Value = 400;
            app.StandingSpacempassEditFieldLabel = uilabel(app.InteriorParametersPanel);
            app.StandingSpacempassEditFieldLabel.Text = 'Standing Space (m²/pass)';
            app.StandingSpaceEditField = uieditfield(app.InteriorParametersPanel, 'numeric');
            app.StandingSpaceEditField.Value = 0.25;
            app.WheelchairZonesDropDownLabel = uilabel(app.InteriorParametersPanel);
            app.WheelchairZonesDropDownLabel.Text = 'Wheelchair Zones #';
            app.WheelchairZonesDropDown = uidropdown(app.InteriorParametersPanel);
            app.WheelchairZonesDropDown.Items = {'0', '1', '2'};
            app.WheelchairZonesDropDown.Value = '1';
            app.NumberofDoorsDropDownLabel = uilabel(app.InteriorParametersPanel);
            app.NumberofDoorsDropDownLabel.Text = 'Number of Doors';
            app.NumberofDoorsDropDown = uidropdown(app.InteriorParametersPanel);
            app.NumberofDoorsDropDown.Items = {'1', '2'};
            app.NumberofDoorsDropDown.Value = '2';
            app.InteriorLayoutDropDownLabel = uilabel(app.InteriorParametersPanel);
            app.InteriorLayoutDropDownLabel.Text = 'Interior Layout';
            app.InteriorLayoutDropDown = uidropdown(app.InteriorParametersPanel);
            app.InteriorLayoutDropDown.Items = {'Coach Layout', 'Urban Layout 1', 'Urban Layour 2'};
            app.InteriorLayoutDropDown.Value = 'Coach Layout';
            app.AirConditioningCheckBox = uicheckbox(app.InteriorParametersPanel);
            app.AirConditioningCheckBox.Text = ' Air Conditioning';
            app.AisleWidthmmEditFieldLabel = uilabel(app.InteriorParametersPanel);
            app.AisleWidthmmEditFieldLabel.Text = 'Aisle Width (mm)';
            app.AisleWidthmmEditField = uieditfield(app.InteriorParametersPanel, 'numeric');
            app.AisleWidthmmEditField.Value = 350;
            app.PowertrainParametersPanel = uipanel(app.DefineNewVehicleTab);
            app.PowertrainParametersPanel.Title = 'Powertrain Parameters';
            app.PowertrainTopologyDropDownLabel = uilabel(app.PowertrainParametersPanel);
            app.PowertrainTopologyDropDownLabel.Text = 'Powertrain Topology';
            app.PowertrainTopologyDropDown = uidropdown(app.PowertrainParametersPanel);
            app.PowertrainTopologyDropDown.Items = {'Single Rear Wheel Drive', 'Dual Rear Wheel Drive', 'Four Wheel Drive 2 Motors', 'Four Motor Drive 4 Motors'};
            app.PowertrainTopologyDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveConceptButtonPushed, true);
            app.PowertrainTopologyDropDown.Value = 'Single Rear Wheel Drive';
            app.TotalPowerkWEditFieldLabel = uilabel(app.PowertrainParametersPanel);
            app.TotalPowerkWEditFieldLabel.Text = 'Total Power (kW)';
            app.TotalPowerkWEditField = uieditfield(app.PowertrainParametersPanel, 'numeric');
            app.TotalPowerkWEditField.Limits = [20 500];
            app.TotalPowerkWEditField.Value = 300;
            app.PowerSplitRatioEditFieldLabel = uilabel(app.PowertrainParametersPanel);
            app.PowerSplitRatioEditFieldLabel.Text = 'Power Split Ratio';
            app.PowerSplitRatioEditField = uieditfield(app.PowertrainParametersPanel, 'numeric');
            app.NumberofGearsDropDownLabel = uilabel(app.PowertrainParametersPanel);
            app.NumberofGearsDropDownLabel.Text = 'Number of Gears';
            app.NumberofGearsDropDown = uidropdown(app.PowertrainParametersPanel);
            app.NumberofGearsDropDown.Items = {'1', '2', '3', '4'};
            app.NumberofGearsDropDown.Value = '1';
            app.BatteryCapacitykWhEditFieldLabel = uilabel(app.PowertrainParametersPanel);
            app.BatteryCapacitykWhEditFieldLabel.Text = 'Battery Capacity (kWh)';
            app.BatteryCapacitykWhEditField = uieditfield(app.PowertrainParametersPanel, 'numeric');
            app.BatteryCapacitykWhEditField.Value = 200;
            app.SaveConceptButton = uibutton(app.PowertrainParametersPanel, 'push');
            app.SaveConceptButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConceptButtonPushed, true);
            app.SaveConceptButton.Text = 'Save Concept';
            app.GearRatioEditFieldLabel = uilabel(app.PowertrainParametersPanel);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.GearRatioEditField = uieditfield(app.PowertrainParametersPanel, 'numeric');
            app.GearRatioEditField.Value = 22;
            app.OptInputTable = uitable(app.DesignTab);
            app.OptInputTable.ColumnName = {'Optimisation Variables'; 'Lower Bound'; 'Upper Bound'; 'Enable'};
            app.OptInputTable.RowName = {};
            app.OptInputTable.ColumnEditable = [false true true true];
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TabGroup3 = uitabgroup(app.ResultsTab);
            app.ParetoFrontTab = uitab(app.TabGroup3);
            app.ParetoFrontTab.Title = 'Pareto Front';
            app.GenerationListBoxLabel = uilabel(app.ParetoFrontTab);
            app.GenerationListBoxLabel.Text = 'Generation';
            app.GenerationListBox = uilistbox(app.ParetoFrontTab);
            app.OptimisationStateTable = uitable(app.ParetoFrontTab);
            app.OptimisationStateTable.ColumnName = {'Optimisation State'};
            app.OptimisationStateTable.RowName = {};
            app.ParetoDesigns = uitable(app.ParetoFrontTab);
            app.ParetoDesigns.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ParetoDesigns.RowName = {};
            app.StopOptimisationButton = uibutton(app.ParetoFrontTab, 'push');
            app.StopOptimisationButton.ButtonPushedFcn = createCallbackFcn(app, @StopOptimisationButtonPushed, true);
            app.StopOptimisationButton.Text = 'Stop Optimisation';
            app.Opt = uiaxes(app.ParetoFrontTab);
            title(app.Opt, 'Pareto Solutions')
            xlabel(app.Opt, 'X')
            ylabel(app.Opt, 'Y')
            app.PropertyComparisonTab = uitab(app.TabGroup3);
            app.PropertyComparisonTab.Title = 'Property Comparison';
            app.AttributeDropDown_2Label_2 = uilabel(app.PropertyComparisonTab);
            app.AttributeDropDown_2Label_2.Text = 'Attribute';
            app.SelectedAttribute_3 = uidropdown(app.PropertyComparisonTab);
            app.SelectedAttribute_3.Items = {};
            app.SelectedAttribute_3.ValueChangedFcn = createCallbackFcn(app, @SelectedAttribute_3ValueChanged, true);
            app.SelectedAttribute_3.Value = {};
            app.PropertyDropDown_2Label_2 = uilabel(app.PropertyComparisonTab);
            app.PropertyDropDown_2Label_2.Text = 'Property';
            app.SelectedProperty_2 = uidropdown(app.PropertyComparisonTab);
            app.SelectedProperty_2.Items = {'Service Performance', 'Accessibility', 'Comfort', 'Functionality', 'Luxury', 'Safety', 'Longitudinal Dynamics', 'Costs', 'Environment'};
            app.SelectedProperty_2.ValueChangedFcn = createCallbackFcn(app, @SelectedProperty_2ValueChanged, true);
            app.SelectedProperty_2.Value = 'Service Performance';
            app.PropertyAxes_2 = uiaxes(app.PropertyComparisonTab);
            title(app.PropertyAxes_2, 'Property')
            xlabel(app.PropertyAxes_2, 'X')
            ylabel(app.PropertyAxes_2, 'Y')
            app.AttributeAxes_2 = uiaxes(app.PropertyComparisonTab);
            title(app.AttributeAxes_2, 'Attributes')
            xlabel(app.AttributeAxes_2, 'X')
            ylabel(app.AttributeAxes_2, 'Y')
            app.AttributeAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ValueFunctionAxes_2 = uiaxes(app.PropertyComparisonTab);
            title(app.ValueFunctionAxes_2, 'Value Function')
            xlabel(app.ValueFunctionAxes_2, 'X')
            ylabel(app.ValueFunctionAxes_2, 'Y')
            app.VehicleSpecificationTab = uitab(app.TabGroup3);
            app.VehicleSpecificationTab.Title = 'Vehicle Specification';
            app.TabGroup6 = uitabgroup(app.VehicleSpecificationTab);
            app.SpecificationsTab = uitab(app.TabGroup6);
            app.SpecificationsTab.Title = 'Specifications';
            app.LengthmmEditFieldLabel = uilabel(app.SpecificationsTab);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.LengthmmEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.LengthmmEditField.ValueDisplayFormat = '%.0f';
            app.LengthmmEditField.Editable = 'off';
            app.WidthmmEditFieldLabel = uilabel(app.SpecificationsTab);
            app.WidthmmEditFieldLabel.Text = 'Width (mm)';
            app.WidthmmEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.WidthmmEditField.ValueDisplayFormat = '%.0f';
            app.WidthmmEditField.Editable = 'off';
            app.HeightmmEditFieldLabel = uilabel(app.SpecificationsTab);
            app.HeightmmEditFieldLabel.Text = 'Height (mm)';
            app.HeightmmEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.HeightmmEditField.ValueDisplayFormat = '%.0f';
            app.HeightmmEditField.Editable = 'off';
            app.CurbMasskgEditFieldLabel = uilabel(app.SpecificationsTab);
            app.CurbMasskgEditFieldLabel.Text = 'Curb Mass (kg)';
            app.CurbMasskgEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.CurbMasskgEditField.ValueDisplayFormat = '%.0f';
            app.CurbMasskgEditField.Editable = 'off';
            app.GrossMasskgEditFieldLabel = uilabel(app.SpecificationsTab);
            app.GrossMasskgEditFieldLabel.Text = 'Gross Mass (kg)';
            app.GrossMasskgEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.GrossMasskgEditField.ValueDisplayFormat = '%.0f';
            app.GrossMasskgEditField.Editable = 'off';
            app.PassengerCapacityEditFieldLabel = uilabel(app.SpecificationsTab);
            app.PassengerCapacityEditFieldLabel.Text = 'Passenger Capacity';
            app.PassengerCapacityEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.PassengerCapacityEditField.ValueDisplayFormat = '%.0f';
            app.PassengerCapacityEditField.Editable = 'off';
            app.SeatingCapacityEditFieldLabel = uilabel(app.SpecificationsTab);
            app.SeatingCapacityEditFieldLabel.Text = 'Seating Capacity';
            app.SeatingCapacityEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.SeatingCapacityEditField.ValueDisplayFormat = '%.0f';
            app.SeatingCapacityEditField.Editable = 'off';
            app.StandingCapacityEditFieldLabel = uilabel(app.SpecificationsTab);
            app.StandingCapacityEditFieldLabel.Text = 'Standing Capacity';
            app.StandingCapacityEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.StandingCapacityEditField.ValueDisplayFormat = '%.0f';
            app.StandingCapacityEditField.Editable = 'off';
            app.WheelchairZonesEditFieldLabel = uilabel(app.SpecificationsTab);
            app.WheelchairZonesEditFieldLabel.Text = '# Wheelchair Zones';
            app.WheelchairZonesEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.WheelchairZonesEditField.ValueDisplayFormat = '%.0f';
            app.WheelchairZonesEditField.Editable = 'off';
            app.TopSpeedkmphEditFieldLabel = uilabel(app.SpecificationsTab);
            app.TopSpeedkmphEditFieldLabel.Text = 'Top Speed (kmph)';
            app.TopSpeedkmphEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.TopSpeedkmphEditField.ValueDisplayFormat = '%.0f';
            app.TopSpeedkmphEditField.Editable = 'off';
            app.GradeabilityLabel_2 = uilabel(app.SpecificationsTab);
            app.GradeabilityLabel_2.Text = 'Gradeability %';
            app.GradeabilityEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.GradeabilityEditField.ValueDisplayFormat = '%.0f';
            app.GradeabilityEditField.Editable = 'off';
            app.RangeEditFieldLabel = uilabel(app.SpecificationsTab);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.RangeEditField.ValueDisplayFormat = '%.0f';
            app.RangeEditField.Editable = 'off';
            app.TurningRadiusmEditFieldLabel = uilabel(app.SpecificationsTab);
            app.TurningRadiusmEditFieldLabel.Text = 'Turning Radius (m)';
            app.TurningRadiusmEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.TurningRadiusmEditField.ValueDisplayFormat = '%.0f';
            app.TurningRadiusmEditField.Editable = 'off';
            app.BatteryCapacitykWhEditField_2Label = uilabel(app.SpecificationsTab);
            app.BatteryCapacitykWhEditField_2Label.Text = 'Battery Capacity (kWh)';
            app.BatteryCapacitykWhEditField_2 = uieditfield(app.SpecificationsTab, 'numeric');
            app.BatteryCapacitykWhEditField_2.ValueDisplayFormat = '%.0f';
            app.BatteryCapacitykWhEditField_2.Editable = 'off';
            app.MotorPowerkWEditFieldLabel = uilabel(app.SpecificationsTab);
            app.MotorPowerkWEditFieldLabel.Text = 'Motor Power (kW)';
            app.MotorPowerkWEditField = uieditfield(app.SpecificationsTab, 'numeric');
            app.MotorPowerkWEditField.ValueDisplayFormat = '%.0f';
            app.MotorPowerkWEditField.Editable = 'off';
            app.GenerateVehiclePackageButton = uibutton(app.SpecificationsTab, 'push');
            app.GenerateVehiclePackageButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePackageButtonPushed, true);
            app.GenerateVehiclePackageButton.Text = 'Generate Vehicle Package';
            app.WheelbasemmEditField_2Label = uilabel(app.SpecificationsTab);
            app.WheelbasemmEditField_2Label.Text = 'Wheelbase (mm)';
            app.WheelbasemmEditField_2 = uieditfield(app.SpecificationsTab, 'numeric');
            app.WheelbasemmEditField_2.ValueDisplayFormat = '%.0f';
            app.WheelbasemmEditField_2.Editable = 'off';
            app.LengthmmEditField_2Label = uilabel(app.SpecificationsTab);
            app.LengthmmEditField_2Label.Text = 'Number of Decks';
            app.LengthmmEditField_2 = uieditfield(app.SpecificationsTab, 'numeric');
            app.LengthmmEditField_2.ValueDisplayFormat = '%.0f';
            app.LengthmmEditField_2.Editable = 'off';
            app.LengthmmEditField_2.Value = 1;
            app.PackageTab = uitab(app.TabGroup6);
            app.PackageTab.Title = 'Package';
            app.PackageAxes = uiaxes(app.PackageTab);
            xlabel(app.PackageAxes, 'X')
            ylabel(app.PackageAxes, 'Y')
            zlabel(app.PackageAxes, 'Z')
            app.TotalCostofOwnershipTab = uitab(app.TabGroup3);
            app.TotalCostofOwnershipTab.Title = 'Total Cost of Ownership';
            app.CostBreakdownTab = uitabgroup(app.TotalCostofOwnershipTab);
            app.OverallCostsTab = uitab(app.CostBreakdownTab);
            app.OverallCostsTab.Title = 'Overall Costs';
            app.OverallFleetCostinSGDEditFieldLabel = uilabel(app.OverallCostsTab);
            app.OverallFleetCostinSGDEditFieldLabel.Text = 'Overall Fleet Cost in SGD';
            app.OverallFleetCostinSGDEditField = uieditfield(app.OverallCostsTab, 'numeric');
            app.OverallFleetCostinSGDEditField.ValueDisplayFormat = '%.0f';
            app.OverallFleetCostinSGDEditField.Editable = 'off';
            app.TotalCostofOwnershipinSGDEditFieldLabel = uilabel(app.OverallCostsTab);
            app.TotalCostofOwnershipinSGDEditFieldLabel.Text = {'Total Cost of '; 'Ownership in SGD'};
            app.TotalCostofOwnershipinSGDEditField = uieditfield(app.OverallCostsTab, 'numeric');
            app.TotalCostofOwnershipinSGDEditField.ValueDisplayFormat = '%.0f';
            app.TotalCostofOwnershipinSGDEditField.Editable = 'off';
            app.TotalCostperkmEditFieldLabel = uilabel(app.OverallCostsTab);
            app.TotalCostperkmEditFieldLabel.Text = 'Total Cost per km ';
            app.TotalCostperkmEditField = uieditfield(app.OverallCostsTab, 'numeric');
            app.TotalCostperkmEditField.ValueDisplayFormat = '%.3f';
            app.TotalCostperkmEditField.Editable = 'off';
            app.TotalCostperpassengerkmLabel = uilabel(app.OverallCostsTab);
            app.TotalCostperpassengerkmLabel.Text = {'Total Cost '; 'per passenger-km'};
            app.TotalCostperpassengerkmEditField = uieditfield(app.OverallCostsTab, 'numeric');
            app.TotalCostperpassengerkmEditField.ValueDisplayFormat = '%.3f';
            app.TotalCostperpassengerkmEditField.Editable = 'off';
            app.AcquisitionCostinSGDEditFieldLabel = uilabel(app.OverallCostsTab);
            app.AcquisitionCostinSGDEditFieldLabel.Text = 'Acquisition Cost in SGD';
            app.AcquisitionCostinSGDEditField = uieditfield(app.OverallCostsTab, 'numeric');
            app.AcquisitionCostinSGDEditField.ValueDisplayFormat = '%.0f';
            app.AcquisitionCostinSGDEditField.Editable = 'off';
            app.TCOAxes = uiaxes(app.OverallCostsTab);
            app.TCOAxes.XTick = [];
            app.TCOPassAxes = uiaxes(app.OverallCostsTab);
            app.TCOPassAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.AcquisitionCostsAxes = uiaxes(app.OverallCostsTab);
            app.AcquisitionCostsAxes.XTick = [];
            app.ComponentCostsTab = uitab(app.CostBreakdownTab);
            app.ComponentCostsTab.Title = 'Component Costs';
            app.LifeCycleAssessmentTab = uitab(app.TabGroup3);
            app.LifeCycleAssessmentTab.Title = 'Life Cycle Assessment';
            app.TotalFleetGHGEmissionsEditFieldLabel = uilabel(app.LifeCycleAssessmentTab);
            app.TotalFleetGHGEmissionsEditFieldLabel.Text = 'Total Fleet GHG Emissions ';
            app.TotalFleetGHGEmissionsEditField = uieditfield(app.LifeCycleAssessmentTab, 'numeric');
            app.GHGEmissionsperkmEditFieldLabel = uilabel(app.LifeCycleAssessmentTab);
            app.GHGEmissionsperkmEditFieldLabel.Text = 'GHG Emissions per km';
            app.GHGEmissionsperkmEditField = uieditfield(app.LifeCycleAssessmentTab, 'numeric');
            app.GHGEmissionsperpassengerkmEditFieldLabel = uilabel(app.LifeCycleAssessmentTab);
            app.GHGEmissionsperpassengerkmEditFieldLabel.Text = {'GHG Emissions per'; 'passenger-km'};
            app.GHGEmissionsperpassengerkmEditField = uieditfield(app.LifeCycleAssessmentTab, 'numeric');
            app.MillionkgCO2eqLabel = uilabel(app.LifeCycleAssessmentTab);
            app.MillionkgCO2eqLabel.Text = 'Million kgCO2eq';
            app.gCO2eqkmLabel = uilabel(app.LifeCycleAssessmentTab);
            app.gCO2eqkmLabel.Text = 'gCO2eq/km';
            app.gCO2eqpassengerkmLabel = uilabel(app.LifeCycleAssessmentTab);
            app.gCO2eqpassengerkmLabel.Text = 'gCO2eq/passenger-km';
            app.GHGEmissionsAxes = uiaxes(app.LifeCycleAssessmentTab);
            app.GHGEmissionsAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.VehicleAssignmentTab = uitab(app.TabGroup3);
            app.VehicleAssignmentTab.Title = 'Vehicle Assignment';
            app.VehicleNumberDropDownLabel = uilabel(app.VehicleAssignmentTab);
            app.VehicleNumberDropDownLabel.Text = 'Vehicle Number';
            app.VehicleNumberDropDown = uidropdown(app.VehicleAssignmentTab);
            app.VehicleNumberDropDown.Items = {};
            app.VehicleNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleNumberDropDownValueChanged, true);
            app.VehicleNumberDropDown.Value = {};
            app.TotalDistanceDrivenkmdayEditFieldLabel = uilabel(app.VehicleAssignmentTab);
            app.TotalDistanceDrivenkmdayEditFieldLabel.Text = {'Total Distance '; 'Driven (km/day)'};
            app.TotalDistanceDrivenkmdayEditField = uieditfield(app.VehicleAssignmentTab, 'numeric');
            app.TotalDistanceDrivenkmdayEditField.Editable = 'off';
            app.NumberofServedTripsEditFieldLabel = uilabel(app.VehicleAssignmentTab);
            app.NumberofServedTripsEditFieldLabel.Text = {'Number of '; 'Served Trips'};
            app.NumberofServedTripsEditField = uieditfield(app.VehicleAssignmentTab, 'numeric');
            app.NumberofServedTripsEditField.Editable = 'off';
            app.AssignmentPlotTab = uitabgroup(app.VehicleAssignmentTab);
            app.VehicleBlockTab = uitab(app.AssignmentPlotTab);
            app.VehicleBlockTab.Title = 'Vehicle Block';
            app.VehicleBlocksAxes = uiaxes(app.VehicleBlockTab);
            app.VehicleBlocksAxes.YLim = [0 2];
            app.VehicleBlocksAxes.YTick = [];
            app.TimeSpaceRepresentationTab = uitab(app.AssignmentPlotTab);
            app.TimeSpaceRepresentationTab.Title = 'Time Space Representation';
            app.TimeSpacePlotAxes = uiaxes(app.TimeSpaceRepresentationTab);
            xlabel(app.TimeSpacePlotAxes, 'Time')
            ylabel(app.TimeSpacePlotAxes, 'Terminal')
            app.EnergyConsumedkWhLabel = uilabel(app.VehicleAssignmentTab);
            app.EnergyConsumedkWhLabel.Text = {'Energy '; 'Consumed (kWh)'};
            app.EnergyConsumedkWhEditField = uieditfield(app.VehicleAssignmentTab, 'numeric');
            app.EnergyConsumedkWhEditField.Editable = 'off';
            app.FleetSizeEditFieldLabel = uilabel(app.VehicleAssignmentTab);
            app.FleetSizeEditFieldLabel.Text = 'Fleet Size';
            app.FleetSizeEditField = uieditfield(app.VehicleAssignmentTab, 'numeric');
            app.FleetSizeEditField.Editable = 'off';
            app.TripNumberDropDownLabel = uilabel(app.VehicleAssignmentTab);
            app.TripNumberDropDownLabel.Text = 'Trip Number';
            app.TripNumberDropDown = uidropdown(app.VehicleAssignmentTab);
            app.TripNumberDropDown.Items = {};
            app.TripNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @TripNumberDropDownValueChanged, true);
            app.TripNumberDropDown.Value = {};
            app.MeanPassengerOccupancyEditFieldLabel = uilabel(app.VehicleAssignmentTab);
            app.MeanPassengerOccupancyEditFieldLabel.Text = {'Mean Passenger '; 'Occupancy #'};
            app.MeanPassengerOccupancyEditField = uieditfield(app.VehicleAssignmentTab, 'numeric');
            app.MeanPassengerOccupancyEditField.Editable = 'off';
            app.VehicleOccupancyAxes = uiaxes(app.VehicleAssignmentTab);
            app.TimetableTab = uitab(app.TabGroup3);
            app.TimetableTab.Title = 'Timetable';
            app.TimetableTable = uitable(app.TimetableTab);
            app.TimetableTable.ColumnName = {'Departure Terminal'; 'Departure Time'; 'Arrival Terminal'; 'Arrival Time'; 'Route No'; 'Direction'};
            app.TimetableTable.RowName = {};
            app.RoutesDropDownLabel = uilabel(app.TimetableTab);
            app.RoutesDropDownLabel.Text = 'Routes';
            app.RoutesDropDown = uidropdown(app.TimetableTab);
            app.RoutesDropDown.Items = {'All Routes'};
            app.RoutesDropDown.Value = 'All Routes';
            app.DirectionDropDownLabel = uilabel(app.TimetableTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.TimetableTab);
            app.DirectionDropDown.Items = {'All', '1', '-1'};
            app.DirectionDropDown.Value = 'All';
            app.TotalTripsEditFieldLabel = uilabel(app.TimetableTab);
            app.TotalTripsEditFieldLabel.Text = 'Total Trips';
            app.TotalTripsEditField = uieditfield(app.TimetableTab, 'numeric');
            app.TotalTripsEditField.ValueDisplayFormat = '%.0f';
            app.TotalTripsEditField.Editable = 'off';
            app.TripsFrequencyAxes = uiaxes(app.TimetableTab);
            app.PassengersTab = uitab(app.TabGroup3);
            app.PassengersTab.Title = 'Passengers';
            app.PassengerTable = uitable(app.PassengersTab);
            app.PassengerTable.ColumnName = {'Passenger Arrival Time'; 'Boarding Time'; 'Waiting Time'; 'Travel Time'; 'Seat Availability'; 'Missed Boardings'; ''};
            app.PassengerTable.RowName = {};
            app.MeanWaitingTimeEditFieldLabel = uilabel(app.PassengersTab);
            app.MeanWaitingTimeEditFieldLabel.Text = 'Mean Waiting Time';
            app.MeanWaitingTimeEditField = uieditfield(app.PassengersTab, 'numeric');
            app.MeanTravelTimeEditFieldLabel = uilabel(app.PassengersTab);
            app.MeanTravelTimeEditFieldLabel.Text = 'Mean Travel Time';
            app.MeanTravelTimeEditField = uieditfield(app.PassengersTab, 'numeric');
            app.WaitingTimeAxes = uiaxes(app.PassengersTab);
            xlabel(app.WaitingTimeAxes, 'Time in Minutes')
            ylabel(app.WaitingTimeAxes, 'Number of Passengers')
            app.VisualiseTab = uitab(app.TabGroup3);
            app.VisualiseTab.Title = 'Visualise';
            app.TimeFrameSliderLabel = uilabel(app.VisualiseTab);
            app.TimeFrameSliderLabel.Text = 'Frames';
            app.TimeFrameSlider = uislider(app.VisualiseTab);
            app.TimeFrameSlider.ValueChangedFcn = createCallbackFcn(app, @TimeFrameSliderValueChanged, true);
            app.SelectVehicletoDisplayLabel = uilabel(app.VisualiseTab);
            app.SelectVehicletoDisplayLabel.Text = 'Select Vehicle to Display';
            app.ListBox = uilistbox(app.VisualiseTab);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 2'};
            app.PlayButton_2 = uibutton(app.VisualiseTab, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.VisAxes = uiaxes(app.VisualiseTab);
            title(app.VisAxes, 'Title')
            xlabel(app.VisAxes, 'X')
            ylabel(app.VisAxes, 'Y')
            zlabel(app.VisAxes, 'Z')
            app.ChargingTab = uitab(app.TabGroup3);
            app.ChargingTab.Title = 'Charging';
            % Show the figure after all components are created
            app.AEVToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AEV(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AEVToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AEVToolUIFigure)
        end
    end
end